classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EchoSwitch                 matlab.ui.control.Switch
        EchoSwitchLabel            matlab.ui.control.Label
        OscillatorSwitch           matlab.ui.control.Switch
        OscillatorSwitchLabel      matlab.ui.control.Label
        HighPassFilterSwitch       matlab.ui.control.Switch
        HighPassFilterSwitchLabel  matlab.ui.control.Label
        LowPassFilterSwitch        matlab.ui.control.Switch
        LowPassFilterSwitchLabel   matlab.ui.control.Label
        FrequencySlider            matlab.ui.control.Slider
        FrequencySliderLabel       matlab.ui.control.Label
        VolumeSlider               matlab.ui.control.Slider
        VolumeSliderLabel          matlab.ui.control.Label
        SOUNDSwitch                matlab.ui.control.Switch
        SOUNDSwitchLabel           matlab.ui.control.Label
        CHOOSEASOUNDDropDown       matlab.ui.control.DropDown
        CHOOSEASOUNDDropDownLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CHOOSEASOUNDDropDown
        function CHOOSEASOUNDDropDownValueChanged(app, event)
            
        end

        % Callback function: SOUNDSwitch, UIAxes
        function SOUNDSwitchValueChanged(app, event)
            value = app.SOUNDSwitch.Value;

            volumeSlider = app.VolumeSlider.Value;
            volume = SoundVolume(volumeSlider/100);

            if (strcmp(value, 'On') == 1)
                sound_drop = app.CHOOSEASOUNDDropDown.Value;
                if (strcmp(sound_drop, 'CAT') == 1)
                    [y,fs] = audioread('CAT.wav');
                    nsemitones = app.FrequencySlider.Value;
                    if strcmp(app.LowPassFilterSwitch.Value, 'On') == 1
                        y = low_pass(y, 1000);
                    elseif strcmp(app.HighPassFilterSwitch.Value, 'On') == 1
                        y = high_pass(y, 2000);
                    end
                    if strcmp(app.EchoSwitch.Value, 'On') == 1
                        y = echo(y, fs);
                    end
                    audioOut = shiftPitch(y,nsemitones);
                    if strcmp(app.OscillatorSwitch.Value, 'On') == 1
                        audioOut = oscillation(audioOut, 10);
                    end
                    sound(audioOut, fs);
                    pause(6);
                    app.SOUNDSwitch.Value = 'Off';

                    Len = length(audioOut); % Length of the signal
                    ModFreq = fft(audioOut); % Perform FFT
                    frequencies = (-Len/2:Len/2-1)*(fs/Len);
                    plot(app.UIAxes,frequencies, abs(fftshift(ModFreq)));
                    xlim(app.UIAxes, [0, 10000]);
                end
                if (strcmp(sound_drop, 'HORSE') == 1)
                    [y,fs] = audioread('HORSE.wav');
                    nsemitones = app.FrequencySlider.Value;
                    if strcmp(app.LowPassFilterSwitch.Value, 'On') == 1
                        y = low_pass(y, 500);
                    elseif strcmp(app.HighPassFilterSwitch.Value, 'On') == 1
                        y = high_pass(y, 2000);
                    end
                    if strcmp(app.EchoSwitch.Value, 'On') == 1
                        y = echo(y, fs);
                    end
                    audioOut = shiftPitch(y,nsemitones);
                    if strcmp(app.OscillatorSwitch.Value, 'On') == 1
                        audioOut = oscillation(audioOut, 10);
                    end
                    sound(audioOut, fs);
                    pause(3);
                    app.SOUNDSwitch.Value = 'Off';

                    Len = length(audioOut); % Length of the signal
                    ModFreq = fft(audioOut); % Perform FFT
                    frequencies = (-Len/2:Len/2-1)*(fs/Len);
                    plot(app.UIAxes,frequencies, abs(fftshift(ModFreq)));
                    xlim(app.UIAxes, [0,10000]);
                end
                if (strcmp(sound_drop, 'WOLF') == 1)
                    [y,fs] = audioread('WOLF.wav');
                    nsemitones = app.FrequencySlider.Value;
                    if strcmp(app.LowPassFilterSwitch.Value, 'On') == 1
                        y = low_pass(y, 500);
                    elseif strcmp(app.HighPassFilterSwitch.Value, 'On') == 1
                        y = high_pass(y, 1000);
                    end
                    if strcmp(app.EchoSwitch.Value, 'On') == 1
                        y = echo(y, fs);
                    end
                    audioOut = shiftPitch(y,nsemitones);
                    if strcmp(app.OscillatorSwitch.Value, 'On') == 1
                        audioOut = oscillation(audioOut, 10);
                    end
                    sound(audioOut, fs);
                    pause(7);
                    app.SOUNDSwitch.Value = 'Off';

                    Len = length(audioOut); % Length of the signal
                    ModFreq = fft(audioOut); % Perform FFT
                    frequencies = (-Len/2:Len/2-1)*(fs/Len);
                    plot(app.UIAxes,frequencies, abs(fftshift(ModFreq)));
                    xlim(app.UIAxes, [0,2000]);
                end
                if (strcmp(sound_drop, 'INTRO') == 1)
                    [y,fs] = audioread('massimo-intro.wav');
                    nsemitones = app.FrequencySlider.Value;
                    if strcmp(app.LowPassFilterSwitch.Value, 'On') == 1
                        y = low_pass(y, 500);
                    elseif strcmp(app.HighPassFilterSwitch.Value, 'On') == 1
                        y = high_pass(y, 1000);
                    end
                    if strcmp(app.EchoSwitch.Value, 'On') == 1
                        y = echo(y, fs);
                    end
                    audioOut = shiftPitch(y,nsemitones);
                    if strcmp(app.OscillatorSwitch.Value, 'On') == 1
                        audioOut = oscillation(audioOut, 10);
                    end
                    sound(audioOut, fs);
                    pause(10);
                    app.SOUNDSwitch.Value = 'Off';

                    Len = length(audioOut); % Length of the signal
                    ModFreq = fft(audioOut); % Perform FFT
                    frequencies = (-Len/2:Len/2-1)*(fs/Len);
                    plot(app.UIAxes,frequencies, abs(fftshift(ModFreq)));
                    xlim(app.UIAxes, [0,2000]);
                end
                if (strcmp(sound_drop, 'FOURIER') == 1)
                    [y,fs] = audioread('massimo-fourier.wav');
                    nsemitones = app.FrequencySlider.Value;
                    if strcmp(app.LowPassFilterSwitch.Value, 'On') == 1
                        y = low_pass(y, 500);
                    elseif strcmp(app.HighPassFilterSwitch.Value, 'On') == 1
                        y = high_pass(y, 1000);
                    end
                    if strcmp(app.EchoSwitch.Value, 'On') == 1
                        y = echo(y, fs);
                    end
                    audioOut = shiftPitch(y,nsemitones);
                    if strcmp(app.OscillatorSwitch.Value, 'On') == 1
                        audioOut = oscillation(audioOut, 10);
                    end
                    sound(audioOut, fs);
                    pause(15);
                    app.SOUNDSwitch.Value = 'Off';

                    Len = length(audioOut); % Length of the signal
                    ModFreq = fft(audioOut); % Perform FFT
                    frequencies = (-Len/2:Len/2-1)*(fs/Len);
                    plot(app.UIAxes,frequencies, abs(fftshift(ModFreq)));
                    xlim(app.UIAxes, [0,2000]);
                end
                if (strcmp(sound_drop, 'NONE') == 1)
                    app.SOUNDSwitch.Value = 'Off';
                end
            end

            if (strcmp(value, 'Off') == 1)
                
            end
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            volumeSlider = app.VolumeSlider.Value;
            volume = SoundVolume(volumeSlider/100);
        end

        % Callback function
        function VolumeSliderValueChanging(app, event)

        end

        % Callback function
        function VolumeSliderValueChanging2(app, event)
            
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            value = app.FrequencySlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'frequency domain')
            xlabel(app.UIAxes, 'Frequencies')
            ylabel(app.UIAxes, 'Amplitudes')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @SOUNDSwitchValueChanged, true);
            app.CHOOSEASOUNDDropDownLabel = uilabel(app.UIFigure);
            app.CHOOSEASOUNDDropDownLabel.Text = 'CHOOSE A SOUND';
            app.CHOOSEASOUNDDropDown = uidropdown(app.UIFigure);
            app.CHOOSEASOUNDDropDown.Items = {'NONE', 'CAT', 'HORSE', 'WOLF', 'INTRO', 'FOURIER'};
            app.CHOOSEASOUNDDropDown.ValueChangedFcn = createCallbackFcn(app, @CHOOSEASOUNDDropDownValueChanged, true);
            app.CHOOSEASOUNDDropDown.Placeholder = 'NONE';
            app.CHOOSEASOUNDDropDown.Value = 'NONE';
            app.SOUNDSwitchLabel = uilabel(app.UIFigure);
            app.SOUNDSwitchLabel.Text = 'SOUND';
            app.SOUNDSwitch = uiswitch(app.UIFigure, 'slider');
            app.SOUNDSwitch.ValueChangedFcn = createCallbackFcn(app, @SOUNDSwitchValueChanged, true);
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 50;
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [-15 15];
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.LowPassFilterSwitchLabel = uilabel(app.UIFigure);
            app.LowPassFilterSwitchLabel.Text = 'Low Pass Filter';
            app.LowPassFilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.HighPassFilterSwitchLabel = uilabel(app.UIFigure);
            app.HighPassFilterSwitchLabel.Text = 'High Pass Filter';
            app.HighPassFilterSwitch = uiswitch(app.UIFigure, 'slider');
            app.OscillatorSwitchLabel = uilabel(app.UIFigure);
            app.OscillatorSwitchLabel.Text = 'Oscillator';
            app.OscillatorSwitch = uiswitch(app.UIFigure, 'slider');
            app.EchoSwitchLabel = uilabel(app.UIFigure);
            app.EchoSwitchLabel.Text = 'Echo';
            app.EchoSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end